classdef test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        initButton                 matlab.ui.control.Button
        workspaceButton            matlab.ui.control.Button
        TabGroup2                  matlab.ui.container.TabGroup
        settingsTab                matlab.ui.container.Tab
        TabGroup                   matlab.ui.container.TabGroup
        SensorsTab                 matlab.ui.container.Tab
        UITableSensors             matlab.ui.control.Table
        MCUTab                     matlab.ui.container.Tab
        TransmissionTab            matlab.ui.container.Tab
        modulesimulationsTab       matlab.ui.container.Tab
        calc_sensorButton          matlab.ui.control.Button
        disp_sensorButton          matlab.ui.control.Button
        maximumnumberofmeasurementsperdayEditFieldLabel  matlab.ui.control.Label
        nmax                       matlab.ui.control.NumericEditField
        uitleg                     matlab.ui.control.Label
        TotalSystemTab             matlab.ui.container.Tab
        enableplottingSwitchLabel  matlab.ui.control.Label
        plotswitch                 matlab.ui.control.Switch
        calc_totalButton           matlab.ui.control.Button
        timehEditFieldLabel        matlab.ui.control.Label
        timeEditField              matlab.ui.control.NumericEditField
        energyJEditFieldLabel      matlab.ui.control.Label
        energyEditField            matlab.ui.control.NumericEditField
        disp_totalButton           matlab.ui.control.Button
        UITableInterval            matlab.ui.control.Table
        Tot_RefreshButton          matlab.ui.control.Button
        ReportButton               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        SaveResultsSwitchLabel     matlab.ui.control.Label
        SaveResultsSwitch          matlab.ui.control.Switch
        runningLampLabel           matlab.ui.control.Label
        runningLamp                matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        init %initmeuk
        Results %results struct of all results of calc_ functions
        list %geeft lbox
        SelectedSensors
        SelectedMCU
        SelectedTransmission
        SelectedNames
        Figures
        SimulationNumber
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.runningLamp.Color = 'r';
            close all;
            [T_alive, E_available, Sensor, Sensorname, MCU, MCUname, Transmission, Transmissionname] = init_main();
            app.init.T_alive = T_alive;
            app.init.E_available = E_available;
            app.init.Sensor = Sensor;
            app.init.Sensorname = Sensorname;
            app.init.MCU = MCU;
            app.init.MCUname = MCUname;
            app.init.Transmission = Transmission;
            app.init.Transmissionname = Transmissionname;
            
            
            checked = false(size(Sensorname,2),1);
            interval = Sensor(3,:).'/24*3600; %converts default measurement into interval.
            parameters = Sensor(4:end,:).';
            sensorname2 = Sensorname.';
            cellarray = [ checked, sensorname2, interval, parameters];
            app.UITableSensors.ColumnEditable(1) = true;
            app.UITableSensors.ColumnEditable(4) = true;%zorgt er voor dat alleen interval colom aangepast kan worden
            app.UITableSensors.ColumnFormat = {'logical',[]};
            app.UITableSensors.Data = [cellarray];
            
            
            app.SimulationNumber.Total = 1;
            app.SimulationNumber.Sensors = 1;
            app.runningLamp.Color = 'g';
        end

        % Button pushed function: initButton
        function initButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            [T_alive, E_available, Sensor, Sensorname, MCU, MCUname, Transmission, Transmissionname] = init_main();
            app.init.T_alive = T_alive;
            app.init.E_available = E_available;
            app.init.Sensor = Sensor;
            app.init.Sensorname = Sensorname;
            app.init.MCU = MCU;
            app.init.MCUname = MCUname;
            app.init.Transmission = Transmission;
            app.init.Transmissionname = Transmissionname;
            
            
            checked = false(size(Sensorname,2),1);
            interval = Sensor(3,:).'/24*3600; %converts default measurement into interval.
            parameters = Sensor(4:end,:).';
            sensorname2 = Sensorname.';
            cellarray = [ checked, sensorname2, interval, parameters];
            app.UITableSensors.Data = [cellarray];
            app.runningLamp.Color = 'g';
            
        end

        % Button pushed function: calc_sensorButton
        function calc_sensorButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            N_Max = app.nmax.Value;
            [app.Results.Sensor, ~] = calc_sensors(app.SelectedSensors , N_Max);
            
            app.SimulationNumber.Sensors = app.SimulationNumber +1;
            app.runningLamp.Color = 'g';
        end

        % Button pushed function: calc_totalButton
        function calc_totalButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            T_Max = app.timeEditField.Value*3600;
            E_Max = app.energyEditField.Value*1000;
            S_Sensors = app.SelectedSensors;
            S_MCU = app.init.MCU(:,1);
            S_Com = app.init.Transmission(:,1);
            S_names = [app.init.Sensorname(1, app.UITableSensors.Data(:,1)=='1') app.init.MCUname(1,1) app.init.Transmissionname(1,1) "Total"];
            
            I_Array = app.UITableInterval.Data(app.UITableInterval.Data(:,1)~=0,:);
            I_Array(:,1) = I_Array(:,1)*3600;
            [Results_calc_totalenergy, P_Sub, E_Sub, time, T_m] = calc_totalenergy(S_Sensors, S_MCU, S_Com, I_Array, T_Max, E_Max);
            app.Results.Total = {P_Sub, E_Sub, time, T_m};
            
            
            if isequal(app.plotswitch.Value, 'On')
                disp_totalenergy(P_Sub, E_Sub, time, T_m, S_names);
            end
            
            if isequal( app.SaveResultsSwitch.Value, 'On' )
                temp = app.Results;         
                filename = strcat('calc_totalV', num2str(app.SimulationNumber.Total), '.mat');
                fullfilename = fullfile('appsave', filename);
                save(fullfilename, 'temp');
            end
            
            app.SimulationNumber.Total = app.SimulationNumber.Total +1;
            app.runningLamp.Color = 'g';
        end

        % Button pushed function: disp_totalButton
        function disp_totalButtonPushed(app, event)


        end

        % Button pushed function: disp_sensorButton
        function disp_sensorButtonPushed(app, event)
            disp_sensors(app.Results.Sensor, app.init.Sensorname(:, app.SelectedSensors));
        end

        % Button pushed function: workspaceButton
        function workspaceButtonPushed(app, event)
            ws_SelectedSensors = app.SelectedSensors;
            test = 0;
        end

        % Cell edit callback: UITableSensors
        function UITableSensorsCellEdit(app, event)
            indices = event.Indices;
            newData = str2double(event.NewData);
            %alle aanpassingen in de tabel worden opgeslagen in
            %app.UITableSensors.Data, als check box aangepast word dan ook
            %aangepast in SelectedItems.
            app.SelectedSensors = app.init.Sensor(:,app.UITableSensors.Data(:,1)=='1');

        end

        % Button pushed function: Tot_RefreshButton
        function Tot_RefreshButtonPushed(app, event)
            app.runningLamp.Color = 'r';
            S_names = [app.init.Sensorname(1, app.UITableSensors.Data(:,1)=='1') app.init.MCUname(1,1) app.init.Transmissionname(1,1) "Total"];
            I_test = [zeros(10,1) false(10,size(app.SelectedSensors,2)+2)];%added 2 for mcu and transmission
            str_logical = repelem({'logical'}, 1, size(app.SelectedSensors,2)+2);
            app.UITableInterval.ColumnFormat = [{'numeric'}, str_logical];
            app.UITableInterval.ColumnName = ['Interval [h]' S_names(1:end-1)];
            app.UITableInterval.ColumnEditable = true;
            app.UITableInterval.Data = [I_test];
            app.runningLamp.Color = 'g';
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
            disp('started making report');
            app.runningLamp.Color = 'r';
            import mlreportgen.dom.*
            D = Document('FromTemplate', 'docx', 'Report/templatetest3.dotx');
            
            open(D);
            
            moveToNextHole(D);
            append(D, "Hello World");
            moveToNextHole(D);
            plot1 = Image('Images/totalenergy5.svg');
            plot1.Style = {ScaleToFit};
            append(D, plot1);
            close(D);
            disp('finished making report');
            app.runningLamp.Color = 'g';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.initButton = uibutton(app.UIFigure, 'push');
            app.initButton.ButtonPushedFcn = createCallbackFcn(app, @initButtonPushed, true);
            app.initButton.Text = 'init';
            app.workspaceButton = uibutton(app.UIFigure, 'push');
            app.workspaceButton.ButtonPushedFcn = createCallbackFcn(app, @workspaceButtonPushed, true);
            app.workspaceButton.Text = 'workspace';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.settingsTab = uitab(app.TabGroup2);
            app.settingsTab.Title = 'settings';
            app.TabGroup = uitabgroup(app.settingsTab);
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.UITableSensors = uitable(app.SensorsTab);
            app.UITableSensors.ColumnName = {'Name'; 'Type'; 'Interval[s]'; 'activated'; 'V [V]'; 'I_m[ma]'; 'T_m[s]'; 'T_p[s]'; 'I_ds[mA]'};
            app.UITableSensors.RowName = {};
            app.UITableSensors.CellEditCallback = createCallbackFcn(app, @UITableSensorsCellEdit, true);
            app.MCUTab = uitab(app.TabGroup);
            app.MCUTab.Title = 'MCU';
            app.TransmissionTab = uitab(app.TabGroup);
            app.TransmissionTab.Title = 'Transmission';
            app.modulesimulationsTab = uitab(app.TabGroup2);
            app.modulesimulationsTab.Title = 'module simulations';
            app.calc_sensorButton = uibutton(app.modulesimulationsTab, 'push');
            app.calc_sensorButton.ButtonPushedFcn = createCallbackFcn(app, @calc_sensorButtonPushed, true);
            app.calc_sensorButton.Text = 'calc_sensor';
            app.disp_sensorButton = uibutton(app.modulesimulationsTab, 'push');
            app.disp_sensorButton.ButtonPushedFcn = createCallbackFcn(app, @disp_sensorButtonPushed, true);
            app.disp_sensorButton.Text = 'disp_sensor';
            app.maximumnumberofmeasurementsperdayEditFieldLabel = uilabel(app.modulesimulationsTab);
            app.maximumnumberofmeasurementsperdayEditFieldLabel.Text = {'maximum number'; 'of measurements '; 'per day'};
            app.nmax = uieditfield(app.modulesimulationsTab, 'numeric');
            app.uitleg = uilabel(app.modulesimulationsTab);
            app.uitleg.Text = '(selected sensors in settings tab will be used)';
            app.TotalSystemTab = uitab(app.TabGroup2);
            app.TotalSystemTab.Title = 'Total System';
            app.enableplottingSwitchLabel = uilabel(app.TotalSystemTab);
            app.enableplottingSwitchLabel.Text = 'enable plotting';
            app.plotswitch = uiswitch(app.TotalSystemTab, 'slider');
            app.plotswitch.Value = 'On';
            app.calc_totalButton = uibutton(app.TotalSystemTab, 'push');
            app.calc_totalButton.ButtonPushedFcn = createCallbackFcn(app, @calc_totalButtonPushed, true);
            app.calc_totalButton.Text = 'calc_total';
            app.timehEditFieldLabel = uilabel(app.TotalSystemTab);
            app.timehEditFieldLabel.Text = 'time [h]';
            app.timeEditField = uieditfield(app.TotalSystemTab, 'numeric');
            app.energyJEditFieldLabel = uilabel(app.TotalSystemTab);
            app.energyJEditFieldLabel.Text = 'energy [J]';
            app.energyEditField = uieditfield(app.TotalSystemTab, 'numeric');
            app.disp_totalButton = uibutton(app.TotalSystemTab, 'push');
            app.disp_totalButton.ButtonPushedFcn = createCallbackFcn(app, @disp_totalButtonPushed, true);
            app.disp_totalButton.Text = 'disp_total';
            app.UITableInterval = uitable(app.TotalSystemTab);
            app.UITableInterval.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableInterval.RowName = {};
            app.Tot_RefreshButton = uibutton(app.TotalSystemTab, 'push');
            app.Tot_RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @Tot_RefreshButtonPushed, true);
            app.Tot_RefreshButton.Text = 'Refresh';
            app.ReportButton = uibutton(app.TotalSystemTab, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Report';
            app.UIAxes = uiaxes(app.TotalSystemTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.TotalSystemTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.SaveResultsSwitchLabel = uilabel(app.TotalSystemTab);
            app.SaveResultsSwitchLabel.Text = 'Save Results';
            app.SaveResultsSwitch = uiswitch(app.TotalSystemTab, 'slider');
            app.SaveResultsSwitch.Value = 'On';
            app.runningLampLabel = uilabel(app.UIFigure);
            app.runningLampLabel.Text = 'running';
            app.runningLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end